classdef FwdModel_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        pB_eit_env_Load                 matlab.ui.control.Button
        eidors_path                     matlab.ui.control.EditField
        PathEIDORSEditFieldLabel        matlab.ui.control.Label
        pB_eit_env_Save                 matlab.ui.control.Button
        fmdl_name                       matlab.ui.control.EditField
        fwd_modelnameEditFieldLabel     matlab.ui.control.Label
        version_info                    matlab.ui.control.EditField
        TabGroup                        matlab.ui.container.TabGroup
        FowardModelTab                  matlab.ui.container.Tab
        MeasurementchamberdesignPanel   matlab.ui.container.Panel
        chamber_name                    matlab.ui.control.EditField
        NameLabel                       matlab.ui.control.Label
        pB_chamber_Save                 matlab.ui.control.Button
        pB_chamber_Load                 matlab.ui.control.Button
        electrodes                      matlab.ui.container.Panel
        electrode_layout_info           matlab.ui.control.TextArea
        pB_elec_layout_Del              matlab.ui.control.Button
        pB_elec_layout_Add              matlab.ui.control.Button
        t_elec_layout                   matlab.ui.control.Table
        BodyPanel                       matlab.ui.container.Panel
        chamber_fem                     matlab.ui.control.NumericEditField
        FEMRefinementEditFieldLabel     matlab.ui.control.Label
        chamber_boxZ                    matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        chamber_boxY                    matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        chamber_boxX                    matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        dD_chamber_typ                  matlab.ui.control.DropDown
        TypDropDownLabel                matlab.ui.control.Label
        Panel_fmdl                      matlab.ui.container.Panel
        sw_show_elec_nb                 matlab.ui.control.Switch
        ElectrodesLabel                 matlab.ui.control.Label
        sw_show_mesh                    matlab.ui.control.Switch
        MeshLabel                       matlab.ui.control.Label
        Panel_fmdl_solver_params        matlab.ui.container.Panel
        dD_fmdl_miscperm_sym            matlab.ui.control.DropDown
        Miscperm_symLabel               matlab.ui.control.Label
        dD_fmdl_systmat                 matlab.ui.control.DropDown
        System_matLabel                 matlab.ui.control.Label
        dD_fmdl_jac                     matlab.ui.control.DropDown
        JacobianLabel                   matlab.ui.control.Label
        dD_fmdl_solve                   matlab.ui.control.DropDown
        SolveLabel                      matlab.ui.control.Label
        pB_fmdl_Save                    matlab.ui.control.Button
        pB_fmdl_Load                    matlab.ui.control.Button
        cB_show_mesh_quality            matlab.ui.control.CheckBox
        pB_fmdl_Make                    matlab.ui.control.Button
        PatternTab                      matlab.ui.container.Tab
        Panel_pattern_param             matlab.ui.container.Panel
        pattern_option                  matlab.ui.control.TextArea
        OptionsLabel                    matlab.ui.control.Label
        pattern_meas_special            matlab.ui.control.EditField
        MeasuserpatternLabel            matlab.ui.control.Label
        dD_pattern_meas_typ             matlab.ui.control.DropDown
        MeasurementpatternLabel         matlab.ui.control.Label
        pattern_inj_special             matlab.ui.control.EditField
        InjuserpatternLabel             matlab.ui.control.Label
        dD_pattern_inj_type             matlab.ui.control.DropDown
        InjectionpatternLabel           matlab.ui.control.Label
        dD_pattern_func                 matlab.ui.control.DropDown
        PatterningfunctionLabel         matlab.ui.control.Label
        pattern_amplitude               matlab.ui.control.NumericEditField
        AmplitudeALabel                 matlab.ui.control.Label
        pattern_option_info             matlab.ui.control.TextArea
        pattern_special_info            matlab.ui.control.TextArea
        dD_actual_inj_nb                matlab.ui.control.DropDown
        InjectionNrDropDownLabel        matlab.ui.control.Label
        cB_pattern_all_display          matlab.ui.control.CheckBox
        cB_pattern_own_display          matlab.ui.control.CheckBox
        cB_pattern_with_meas            matlab.ui.control.CheckBox
        cB_pattern_animated             matlab.ui.control.CheckBox
        cB_pattern_hold                 matlab.ui.control.CheckBox
        pB_pattern_Make                 matlab.ui.control.Button
        MeasPatternPanel                matlab.ui.container.Panel
        MeasPatternTable                matlab.ui.control.Table
        pB_pattern_Display              matlab.ui.control.Button
        InjectionPatternPanel           matlab.ui.container.Panel
        InjectionPatternTable           matlab.ui.control.Table
        SolvefowardTab                  matlab.ui.container.Tab
        medium_conduct                  matlab.ui.control.NumericEditField
        MediumconductivityEditFieldLabel  matlab.ui.control.Label
        save_fwd_sol                    matlab.ui.control.CheckBox
        ObjectsPanel                    matlab.ui.container.Panel
        RowMinusButton_CellPosition     matlab.ui.control.Button
        RowPlusButton_CellPosition      matlab.ui.control.Button
        t_objects                       matlab.ui.control.Table
        add_obj_inFEM                   matlab.ui.control.CheckBox
        SolveFowardButton               matlab.ui.control.Button
        ax_sim_data                     matlab.ui.control.UIAxes
        SolveInverseTab                 matlab.ui.container.Tab
        loadandrecontaructmeasButton    matlab.ui.control.Button
        Makeinv_mdlButton               matlab.ui.control.Button
        SimGREITTable                   matlab.ui.control.Table
        SlicesforevalPanel              matlab.ui.container.Panel
        RowMinusButton_SimSlice         matlab.ui.control.Button
        RowPlusButton_SimSlice          matlab.ui.control.Button
        SimEvalSliceUITable             matlab.ui.control.Table
        inv_modelsolvingparameterPanel  matlab.ui.container.Panel
        imdljacobian_bkgndfuncDropDown  matlab.ui.control.DropDown
        imdljacobian_bkgndfuncDropDownLabel  matlab.ui.control.Label
        imdlhyperparameterparametersDropDown  matlab.ui.control.DropDown
        imdlhyperparameterparametersLabel  matlab.ui.control.Label
        imdljacobian_bkgndvalueEditField  matlab.ui.control.NumericEditField
        imdljacobian_bkgndvalueEditFieldLabel  matlab.ui.control.Label
        imdlhyperparametervalueEditField  matlab.ui.control.NumericEditField
        imdlhyperparametervalueEditFieldLabel  matlab.ui.control.Label
        imdlreconst_typeDropDown        matlab.ui.control.DropDown
        imdlreconst_typeDropDownLabel   matlab.ui.control.Label
        imdlhyperparameterfuncDropDown  matlab.ui.control.DropDown
        imdlhyperparameterfuncDropDownLabel  matlab.ui.control.Label
        imdlRtR_priorDropDown           matlab.ui.control.DropDown
        imdlRtR_priorDropDownLabel      matlab.ui.control.Label
        imdlsolveDropDown               matlab.ui.control.DropDown
        imdlsolveDropDownLabel          matlab.ui.control.Label
        SimulationdatasetnameEditField  matlab.ui.control.EditField
        SimulationdatasetnameEditFieldLabel  matlab.ui.control.Label
        LoadsimulationresultsButton     matlab.ui.control.Button
        SavesimulationresultsButton     matlab.ui.control.Button
        Transparency3DimdlSlider        matlab.ui.control.Slider
        Transparency3DimdlSliderLabel   matlab.ui.control.Label
        SliceZHeightSlider              matlab.ui.control.Slider
        SliceZHeightSliderLabel         matlab.ui.control.Label
        Makeinv_mdlSimulateButton       matlab.ui.control.Button
        Generate_user_entryTab          matlab.ui.container.Tab
        size_samplesnbEditField         matlab.ui.control.NumericEditField
        size_samplesnbEditFieldLabel    matlab.ui.control.Label
        size_single_datanbEditField     matlab.ui.control.NumericEditField
        size_single_datanbEditFieldLabel  matlab.ui.control.Label
        GenerateTrainingSamplesButton   matlab.ui.control.Button
        TypofrandominvsolverDropDown    matlab.ui.control.DropDown
        TypofrandominvsolverDropDownLabel  matlab.ui.control.Label
        typeofartifactsDropDown         matlab.ui.control.DropDown
        typeofartifactsDropDownLabel    matlab.ui.control.Label
        Savename_user_entrymatButton    matlab.ui.control.Button
        SNREditField                    matlab.ui.control.NumericEditField
        SNREditFieldLabel               matlab.ui.control.Label
        rangecellconductivityEditField  matlab.ui.control.EditField
        rangecellconductivityEditFieldLabel  matlab.ui.control.Label
        rangecellradiusEditField        matlab.ui.control.EditField
        rangecellradiusEditFieldLabel   matlab.ui.control.Label
        rangebufferconductivityEditField  matlab.ui.control.EditField
        rangebufferconductivityEditFieldLabel  matlab.ui.control.Label
        rangenumofcellEditField         matlab.ui.control.EditField
        rangenumofcellEditFieldLabel    matlab.ui.control.Label
        nameEditField                   matlab.ui.control.EditField
        nameEditFieldLabel              matlab.ui.control.Label
        num_trainingdataEditField       matlab.ui.control.NumericEditField
        num_trainingdataEditFieldLabel  matlab.ui.control.Label
    end

    properties (Access = public)
        Path
        Figure
        CallingApp
        flag
        eit_env EIT_env
    end

    methods (Access = private)
        
        function app= Init_FwdModel_App(app)
            % Init the paths variable and
            %

            app.Init_Paths();
            app.Start_EIDORS_ToolBox();
            app.Init_globalVar_EIDORS();

            %% Init Dropdown items
            app.dD_chamber_typ.Items =app.eit_env.setup.chamber.supported_forms();
            
            app.dD_pattern_func.Items=app.eit_env.setup.pattern.get_generating_func();
            % >>> the pattern list for inj and meas are in setDataGUI()
            
            app.dD_fmdl_solve.Items = app.eit_env.fwd_model.supported_solvers();
            app.dD_fmdl_jac.Items = app.eit_env.fwd_model.supported_jacobians();
            app.dD_fmdl_systmat.Items = app.eit_env.fwd_model.supported_sys_mats();
            app.dD_fmdl_miscperm_sym.Items = app.eit_env.fwd_model.supported_perm_sym();


            app.t_elec_layout.ColumnFormat={...
                app.eit_env.setup.elec_layout.supported_layouts(),...
                'numeric',...
                'numeric',...
                app.eit_env.setup.elec_layout.supported_elec_pos(),...
                app.eit_env.setup.elec_layout.supported_elec_forms(),...
                'numeric',...
                'numeric',...
                'numeric'};
            struct4gui= app.eit_env.setup.get_elec_layout_4_gui();
            app.t_elec_layout.ColumnName= fieldnames(struct4gui(1));
            
            app.t_elec_layout.Data=[struct2cell(struct4gui)]';
            
            app.t_objects.ColumnName={'Type', 'Position', 'Dimensions','Conductivity' };
            app.t_objects.Data = {'Cell' '[0,0,0]' '[0.1, 0, 0]' '[0.1]'};
            app.t_objects.ColumnFormat = {app.eit_env.sim.objects.allowed_type() 'char' 'char' 'char'};

            app.SimEvalSliceUITable.Data= {inf inf 0; inf inf 0; inf inf 0};
            

        end

        function app= Init_Paths(app)
            %% INIT Paths
            % set the used paths for Saving/Loading functions
            % app.Path.CurrentFolder is Define before the call of this function

            app.Path.Programms = [ app.Path.CurrentFolder filesep 'Sub_Programms'];
            app.Path.Setups = [app.Path.CurrentFolder filesep 'Setups'];
            % app.Path.FrequencySetups = [app.Path.CurrentFolder '\Setups\FrequencySetups'];
            % app.Path.InjectionPatterns= [app.Path.CurrentFolder '\Setups\InjectionPatterns'];
            % app.Path.BodyElectrodeSetups=[app.Path.CurrentFolder '\Setups\BodyElectrodeSetups'];
            app.Path.ChambersDesigns=[app.Path.CurrentFolder filesep 'Setups' filesep 'ChambersDesigns'];
            app.Path.fmdl=[app.Path.CurrentFolder filesep 'fmdl'];

            % test the paths if not exist they are created
            Test_Path(app.Path);
            % add the paths to matlab
            p=struct2cell(app.Path);
            for i=1:size(p,1)
                addpath(p{i});
            end

            if isempty(app.CallingApp)
                cd(app.Path.CurrentFolder); % go to current folder for this App
            else

            end
        end

        function app = Init_globalVar_EIDORS(app)
            global EIDORS
            Reset=0;
            if isempty(app.CallingApp)
                % if standalone Reset EIDORS to Default values contained in EIDORS_default.mat
                Reset= 1;
            else
                if isempty(EIDORS) % if by calling THIS APP, the var EIDORS do not exist Reset
                    Reset= 1;
                end
            end

            if Reset
                clear EIDORS
                clear global EIDORS
                global EIDORS
                tmp =load([app.Path.Setups filesep 'EIDORS_default.mat']);
                EIDORS=tmp.EIDORS;
                app.flag.redraw=0;
                EIDORS.sim.netgenAdditionalText="";

            end

        end

        function app= Start_EIDORS_ToolBox(app)

            if isempty(app.CallingApp)
                app.Path.EIDORS=Start_EIDORS();
            else
                disp('EIDORS already started from MainApp');
                app.Path.EIDORS= ['Started from MainApp: ' app.CallingApp.Path.EIDORS];
            end
        end
        

        function app=GetDataGUI(app)

%             EIDORS.sim.name=app.SimulationdatasetnameEditField.Value;

%             EIDORS.sim.cell= cell2struct(app.t_objects.Data, app.t_objects.ColumnName,2);
%             EIDORS.sim.bufferConduct=app.medium_conduct.Value;
%             EIDORS.sim.cellConduct=app.CellconductEditField.Value;
%             EIDORS.sim.layerConduct=app.LayerconductEditField.Value;

%             EIDORS.sim.fmdl_jacobian=app.dD_fmdl_jac.Value;%
%             EIDORS.sim.fmdl_miscperm_sym=app.dD_fmdl_miscperm_sym.Value;%
%             EIDORS.sim.fmdl_solve=app.dD_fmdl_solve.Value;%
%             EIDORS.sim.fmdl_system_mat=app.dD_fmdl_systmat.Value;%

%             EIDORS.sim.imdl_hyperparameterfunc=app.imdlhyperparameterfuncDropDown.Value;
%             EIDORS.sim.imdl_hyperparameterparameters=app.imdlhyperparameterparametersDropDown.Value;
%             EIDORS.sim.imdl_hyperparametervalue=app.imdlhyperparametervalueEditField.Value;
%             EIDORS.sim.imdl_jacobian_bkgndfunc=app.imdljacobian_bkgndfuncDropDown.Value;
%             EIDORS.sim.imdl_jacobian_bkgndvalue=app.imdljacobian_bkgndvalueEditField.Value;
%             EIDORS.sim.imdl_reconst_type=app.imdlreconst_typeDropDown.Value;
%             EIDORS.sim.imdl_RtR_prior=str2func(app.imdlRtR_priorDropDown.Value);
%             EIDORS.sim.imdl_solve=app.imdlsolveDropDown.Value;

%             EIDORS.sim.transparency=app.Transparency3DimdlSlider.Value/100;
%             EIDORS.sim.levels= cell2mat(app.SimEvalSliceUITable.Data);

        end
        function plotFem(app, fmdl, figName, colorBar)
            
            op=[0,0,0];
            
            if logical(colorBar)
                op(1)= 1;
            end
            if app.sw_show_elec_nb.Value
                op(2)=1.012;
            end
            
            h= getCurrentFigure_with_figName(figName);
            h= show_fem(fmdl ,op);
            
            if ~app.sw_show_mesh.Value
                set(h,'EdgeColor','none');
            end
       
        end
        
        function app= SetDataGUI(app)
            global EIDORS

            if ~isempty(app.CallingApp)
                UpdateDatefromsim(app.CallingApp); %% set data on calling App
            end
            
            if app.eit_env.setup.chamber.height()==0
                
            else
                app.SliceZHeightSlider.Limits=[-EIDORS.chamber.body.height/2,EIDORS.chamber.body.height/2];
            end
            

            app.eidors_path.Value=app.Path.EIDORS;
            
            disp('Update GUI from EIT_env')
            % Chamber
            app.chamber_name.Value    = app.eit_env.setup.chamber.name ;   
            app.dD_chamber_typ.Value     = app.eit_env.setup.chamber.form ;
            app.chamber_boxX.Value    = app.eit_env.setup.chamber.length();
            app.chamber_boxZ.Value    = app.eit_env.setup.chamber.width();
            app.chamber_boxY.Value    = app.eit_env.setup.chamber.height();
            app.chamber_fem.Value     = app.eit_env.setup.chamber.femRefinement;
            
            % Electrode layout
            struct4gui= app.eit_env.setup.get_elec_layout_4_gui();
            t=[];
            for i=1:length(struct4gui)
                t=[t; struct2cell(struct4gui(i))'];
            end
            app.t_elec_layout.Data=t;
            
            %fmdl solver
            fmdl= app.eit_env.fwd_model;
            app.fmdl_name.Value= fmdl.name;
            app.dD_fmdl_solve.Value= fmdl.solve;
            app.dD_fmdl_jac.Value= fmdl.jacobian;
            app.dD_fmdl_systmat.Value= fmdl.system_mat;
            app.dD_fmdl_miscperm_sym.Value= fmdl.misc.perm_sym;
            
            %pattern
            %update pattern choices 
            app.dD_pattern_inj_type.Items=app.eit_env.setup.pattern.get_patterns();
            app.dD_pattern_meas_typ.Items=app.eit_env.setup.pattern.get_patterns();
            app.dD_pattern_inj_type.ItemsData=app.eit_env.setup.pattern.get_patterns();
            app.dD_pattern_meas_typ.ItemsData=app.eit_env.setup.pattern.get_patterns();
            
            pattern= app.eit_env.setup.pattern;
            app.pattern_amplitude.Value = pattern.injAmplitude ;
            app.dD_pattern_inj_type.Value = pattern.injType;
            app.pattern_inj_special.Value = pattern.injSpecial;
            app.dD_pattern_meas_typ.Value = pattern.measType;
            app.pattern_meas_special.Value =pattern.measSpecial;
            app.pattern_option.Value = pattern.patternOption;
            app.dD_pattern_func.Value = pattern.patternFunc;
            
            % update patterns table
            [inj, meas]=app.eit_env.fwd_model.extract_pattern_for_display();
            list_indx_inj= [1:size(inj,1)];
            
            app.InjectionPatternTable.Data = [list_indx_inj' inj];
 
            inj_indx= str2num(app.dD_actual_inj_nb.Value);
            app.MeasPatternTable.Data = meas(:,:,inj_indx);
            
            
            %Simulation
            % medium
            app.medium_conduct.Value = app.eit_env.sim.mediumConduct;
            % Electrode layout
            struct4gui= app.eit_env.sim.get_objects_4_gui();
            t=[];
            for i=1:length(struct4gui)
                t=[t; struct2cell(struct4gui(i))'];
            end
            app.t_objects.Data = t;


            app.SimulationdatasetnameEditField.Value=EIDORS.sim.name;

            app.imdlhyperparameterfuncDropDown.Value = EIDORS.sim.imdl_hyperparameterfunc;
            app.imdlhyperparameterparametersDropDown.Value = EIDORS.sim.imdl_hyperparameterparameters;
            app.imdlhyperparametervalueEditField.Value = EIDORS.sim.imdl_hyperparametervalue;
            app.imdljacobian_bkgndfuncDropDown.Value = EIDORS.sim.imdl_jacobian_bkgndfunc;
            app.imdljacobian_bkgndvalueEditField.Value = EIDORS.sim.imdl_jacobian_bkgndvalue;
            app.imdlreconst_typeDropDown.Value = EIDORS.sim.imdl_reconst_type;
            app.imdlRtR_priorDropDown.Value =func2str(EIDORS.sim.imdl_RtR_prior);
            app.imdlsolveDropDown.Value = EIDORS.sim.imdl_solve;

            app.SimulationdatasetnameEditField.Value = EIDORS.sim.name;
            app.Transparency3DimdlSlider.Value = EIDORS.sim.transparency*100;
            app.SimEvalSliceUITable.Data= mat2cell(EIDORS.sim.levels, ones(1,size(EIDORS.sim.levels,1)),ones(1,size(EIDORS.sim.levels,2)));
            app.SimGREITTable.Data=[struct2cell(EIDORS.sim.GREIT)]';
            app.SimGREITTable.ColumnName=fieldnames(EIDORS.sim.GREIT);

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            %% Add the folder of THIS FILE and its subfolders
            p= mfilename('fullpath');
            indx = strfind(p, filesep);
            app.Path.CurrentFolder = p(1:indx(end)-1);
            addpath(replace(genpath(app.Path.CurrentFolder),[filesep 'old'],''));

            if exist('MainApp',"var")
                % Started from another App
                app.CallingApp = MainApp;
            end
            app.eit_env=EIT_env();
            
            app.Init_FwdModel_App();
            app.SetDataGUI();% update EIDORS Path on GUI
            % init some dropdown Menus

             app.pB_fmdl_MakePushed();
             app.pB_pattern_MakePushed();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the Plot Options button in main app
            if ~isempty(app.CallingApp)
                app.CallingApp.FowardModelconfigButton.Enable = 'on';
                UpdateDatefromsim(app.CallingApp);
            end
            delete(app);
        end

        % Button pushed function: pB_eit_env_Save
        function pB_eit_env_SavePushed(app, event)
            % Refactored
            app.eit_env.save( ...
                app.Path.Setups, ...
                'Environement_name');
            figure(app.UIFigure);
        end

        % Button pushed function: pB_eit_env_Load
        function pB_eit_env_LoadPushed(app, event)
            [new_env, success]= app.eit_env.load(app.Path.Setups,"");
            if success
                app.eit_env= new_env;
                app.SetDataGUI();
            end
            figure(app.UIFigure);
        end

        % Button pushed function: pB_fmdl_Make
        function pB_fmdl_MakePushed(app, event)
            
            % Chamber
            app.eit_env.setup.chamber = EIT_chamber( ...
                app.chamber_name.Value, ...
                [app.chamber_boxX.Value,app.chamber_boxY.Value, app.chamber_boxZ.Value], ...
                app.chamber_fem.Value, ...
                app.dD_chamber_typ.Value);
            % Electrode layout
            layout_data= cell2struct(app.t_elec_layout.Data, ...
                app.t_elec_layout.ColumnName, 2);
            app.eit_env.setup.reset_elec_layout();
            for i=1:length(layout_data)
                app.eit_env.setup.add_elec_layout( ...
                    EIT_elec_layout(layout_data(i)) );           
            end
            
            % set the solver only when fwd model is created
            app.eit_env.fwd_model.set_solver( ...
                app.fmdl_name.Value, ...
                app.dD_fmdl_solve.Value, ...
                app.dD_fmdl_jac.Value, ...
                app.dD_fmdl_systmat.Value, ...
                app.dD_fmdl_miscperm_sym.Value);
            
            % app.FmdlGenerationFunctionDropDown.Value %
            % if needed transfer this to create_fwd_model            
            [fmdl, success]=app.eit_env.create_fwd_model('');
            if success
                app.SetDataGUI();
                app.plotFem(app.eit_env.fwd_model.fmdl,'Mesh', 0);
            end
        end

        % Button pushed function: pB_fmdl_Save
        function pB_fmdl_SavePushed(app, event)
            app.eit_env.save( ...
                app.Path.Setups, ...
                app.eit_env.fwd_model.name);
            figure(app.UIFigure);
        end

        % Button pushed function: pB_fmdl_Load
        function pB_fmdl_LoadPushed(app, event)
            [new_env, success]= app.eit_env.load(app.Path.Setups,"");
            if success
                app.eit_env.fwd_model= new_env.fwd_model;
                app.SetDataGUI();
            end
            figure(app.UIFigure);
            
%             
%             global EIDORS
%             if LoadSave(app,'load','fmdl','user')
%                 app.SetDataGUI();% update
%                 app.flag.redraw=1;
% %                 app.GetDataGUI();
%                 create_fmdl();
%             end
        end

        % Button pushed function: pB_chamber_Save
        function pB_chamber_SavePushed(app, event)
            % Refactored
            app.eit_env.save( ...
                app.Path.ChambersDesigns, ...
                app.eit_env.setup.chamber.name);
            figure(app.UIFigure);
        end

        % Button pushed function: pB_chamber_Load
        function pB_chamber_LoadPushed(app, event)
            % Refactored
            [new_env, success]= app.eit_env.load( ...
                app.Path.ChambersDesigns, ...
                "");
            if success
                app.eit_env.setup.chamber= new_env.setup.chamber;
                app.SetDataGUI();
            end
            figure(app.UIFigure);
        end

        % Button pushed function: pB_pattern_Make
        function pB_pattern_MakePushed(app, event)

            app.eit_env.setup.pattern= EIT_pattern( ...
                app.pattern_amplitude.Value, ...
                app.dD_pattern_inj_type.Value, ...
                app.pattern_inj_special.Value, ...
                app.dD_pattern_meas_typ.Value, ...
                app.pattern_meas_special.Value, ...
                app.pattern_option.Value, ...
                app.dD_pattern_func.Value);
            app.eit_env.generate_pattern();
            
            % set the list of injections
            [inj, meas]=app.eit_env.fwd_model.extract_pattern_for_display();
            list_indx_inj= [1:size(inj,1)];
            app.dD_actual_inj_nb.Items= string(num2cell(list_indx_inj));
            app.dD_actual_inj_nb.Value= "1"; %% reset to injection nr 1
            
            app.SetDataGUI(); 
            
        end

        % Button pushed function: pB_elec_layout_Add
        function pB_elec_layout_AddPushed(app, event)


            app.t_elec_layout.Data=[app.t_elec_layout.Data; { 'Ring' 4 16 'Top' 'Circular' 0.5 0 0.01}];
        end

        % Button pushed function: pB_elec_layout_Del
        function pB_elec_layout_DelPushed(app, event)
            app.t_elec_layout.Data=app.t_elec_layout.Data(1:end-1,:);
        end

        % Value changed function: dD_actual_inj_nb
        function dD_actual_inj_nbValueChanged(app, event)
            app.SetDataGUI();
        end

        % Button pushed function: pB_pattern_Display
        function pB_pattern_DisplayPushed(app, event)

            fmdl=app.eit_env.fwd_model.fmdl;
            
            animated = app.cB_pattern_animated.Value; % value : 0 or 1
            with_meas = app.cB_pattern_with_meas.Value; % value : 0 or 1
            display_all=app.cB_pattern_all_display.Value; % value : 0 or 1
            
            if app.cB_pattern_own_display.Value==1
                Own_DisplayScript_Pattern(app.eit_env.fwd_model, display_all,animated,with_meas);
            else
                displayStimOption={'n','y','na','ya'};
                option= displayStimOption{with_meas+2*animated+1};
                show_stim_meas_pattern(fmdl,option);
            end

        end

        % Button pushed function: SolveFowardButton
        function SolveFowardButtonPushed(app, event)

            
            %% set simulation Medium / Objects
            medium= app.medium_conduct.Value;
            objects= cell2struct(app.t_objects.Data, ...app.flag
                app.t_objects.ColumnName, 2);
            
            app.eit_env.set_sim(medium, objects);
            
            %% solve foward
            % app.add_obj_inFEM not implemented yet!
            app.eit_env.solve_fwd(app.add_obj_inFEM.Value);
            
            %%Plot 3D images
            figName= '3D Image of homogenious conductivity';
            app.plotFem(app.eit_env.sim.img_h, figName, 1);
            figName= '3D Image of inhomogenious conductivity';
            app.plotFem(app.eit_env.sim.img_ih, figName, 1);

%             show_cell(h,EIDORS.sim.iimg.calc_slices.levels,1)
            disp('Generate model with cell: Done!');
            
            
            %& Plot measurements
            axesHandle = app.ax_sim_data;%findobj('Tag', 'tagForYourAxes');
            ih = app.eit_env.sim.data_ih.meas;
            h = app.eit_env.sim.data_h.meas;
            plot(axesHandle,[ih, h, ih-h]);
            legend(axesHandle,{'ih', 'h', 'diff'}, "Location","northeast");
            
            if app.save_fwd_sol.Value==true
                name= [app.fmdl_name.Value '.mat'];
                file=path_join(app.Path.CurrentFolder, name);
                X= ih;
                X_ih= ih;
                X_h= h;
                eit_env= app.eit_env;
                [file, path] =uiputfile({'*.mat','*.m'}, 'Enter a filename for saving the foward solution', file);
                if file==0
                    return;
                end
                file=path_join(path, file);
                save(file, "X", "X_ih", "X_h", "eit_env", '-mat');
            end
            app.SetDataGUI();
        end

        % Button pushed function: RowPlusButton_CellPosition
        function RowPlusButton_CellPositions_Pushed(app, event)
            app.t_objects.Data=[app.t_objects.Data; {'Cell' '[0,0,0]' '[0.1, 0, 0]' '[0.1]'};];
        end

        % Button pushed function: RowMinusButton_CellPosition
        function RowMinusButton_CellPositions_Pushed(app, event)
            app.t_objects.Data=app.t_objects.Data(1:end-1,:);
        end

        % Button pushed function: Makeinv_mdlSimulateButton
        function Makeinv_mdlSimulateButtonPushed(app, event)
            app.GetDataGUI();
            Simulate_fwd_model();
            app.SetDataGUI();
        end

        % Value changed function: SliceZHeightSlider
        function SliceZHeightSliderValueChanged(app, event)
            value = app.SliceZHeightSlider.Value;
            global EIDORS
            imag_layers =  [inf, inf, value];
            t= ['Slice at Z-height ' num2str(value, '%.3f') ' mm'];
            h= getCurrentFigure_with_figName(t);
            h= show_slices(EIDORS.sim.iimg, imag_layers);
            show_cell(h,imag_layers,0);
        end

        % Value changed function: Transparency3DimdlSlider
        function Transparency3DimdlSliderValueChanged(app, event)
            global EIDORS
            app.flag.redraw=1;
            app.GetDataGUI();
            Simulate_fwd_model();
        end

        % Button pushed function: SavesimulationresultsButton
        function SavesimulationresultsButtonPushed(app, event)
            app.GetDataGUI();
            LoadSave(app,'save','simulation','user');
            app.SetDataGUI();
            figure(app.UIFigure);
        end

        % Button pushed function: LoadsimulationresultsButton
        function LoadsimulationresultsButtonPushed(app, event)
            app.GetDataGUI();
            LoadSave(app,'load','simulation','user');
            app.SetDataGUI();
            figure(app.UIFigure);
        end

        % Value changed function: sw_show_mesh
        function sw_show_meshValueChanged(app, event)
            app.plotFem(app.eit_env.fwd_model.fmdl,'Mesh', 0);
        end

        % Value changed function: sw_show_elec_nb
        function sw_show_elec_nbValueChanged(app, event)
            app.plotFem(app.eit_env.fwd_model.fmdl,'Mesh', 0);
        end

        % Button pushed function: RowPlusButton_SimSlice
        function RowPlusButton_SimSlicePushed(app, event)
            app.SimEvalSliceUITable.Data=[app.SimEvalSliceUITable.Data; {Inf Inf 0}];
        end

        % Button pushed function: RowMinusButton_SimSlice
        function RowMinusButton_SimSlicePushed(app, event)
            app.SimEvalSliceUITable.Data=app.SimEvalSliceUITable.Data(1:end-1,:);
        end

        % Button pushed function: Makeinv_mdlButton
        function Makeinv_mdlButtonPushed(app, event)
%             global EIDORS
%             app.GetDataGUI();
%             create_imdl();
            app.eit_env.inv_model.set_inv_solver( ...
                '', ...
                app.imdlsolveDropDown.Value, ...
                app.imdlRtR_priorDropDown.Value, ...
                '', ...
                app.imdlhyperparametervalueEditField.Value, ...
                app.imdlhyperparameterfuncDropDown.Value, ...
                app.imdlhyperparameterparametersDropDown.Value, ...
                app.imdljacobian_bkgndvalueEditField.Value, ...
                app.imdljacobian_bkgndfuncDropDown.Value, ...
                app.imdlreconst_typeDropDown.Value);
            
            app.eit_env.inv_model.set_fwd_model( ...
                app.eit_env.fwd_model);
            
        end

        % Value changed function: dD_pattern_func
        function dD_pattern_funcValueChanged(app, event)
            % set the pattren function choosen
            app.eit_env.setup.pattern.init_pattern_func(app.dD_pattern_func.Value);
            app.SetDataGUI();
        end

        % Callback function
        function StateBtn_ReconstValueChanged(app, event)
            if app.StateBtn_Reconst.Value
                app.StateBtn_Reconst.Text="Stop reconstruction"
            else
                app.StateBtn_Reconst.Text="Start reconstruction"
            end

            while (app.StateBtn_Reconst.Value ==1)
                Reconstruction2
                % your iterative computation here
                i = i + 1;
            end
        end

        % Button pushed function: loadandrecontaructmeasButton
        function loadandrecontaructmeasButtonPushed(app, event)
            global EIDORS
            app.GetDataGUI();

            meas=load(uigetfile('.txt'));

            EIDORS.sim.data_h.meas=meas(:,1);
            EIDORS.sim.data_ih.meas=meas(:,2);
            Simulate_fwd_model()
            app.SetDataGUI();
        end

        % Button pushed function: Savename_user_entrymatButton
        function Savename_user_entrymatButtonPushed(app, event)
            global EIDORS

            u_entry= user_entry();
            u_entry.fmdl = EIDORS.fmdl;
            u_entry.chamber = EIDORS.chamber;


            u_entry.net_file_name =app.nameEditField.Value;                      
    
            u_entry.range_num_of_cells = str2num(app.rangenumofcellEditField.Value);            % = 10; % or e.g [5 10], one number indicates the maximum possible number of cells, two numbers means the range of possible number of cells, e.g. [5 10] from 5 to 10 cells
            u_entry.range_buffer_conductivity = str2num(app.rangebufferconductivityEditField.Value);     % = 5; % or e.g [5 10], one number indicates the maximum possible conductivity of the buffer, two numbers means the range of possible conductivity of the buffer
            u_entry.range_cell_conductivity = str2num(app.rangecellconductivityEditField.Value);       % = [5 10]; % or e.g [5 10], one number indicates the maximum possible conductivity of cells, two numbers means the range of possible conductivitie of cells
            u_entry.range_cell_radius = str2num(app.rangecellradiusEditField.Value);             % = 0.5; % or e.g [0.5 1.0], one number indicates the maximum possible radius of cells, two numbers means the range of possible radius of cells
            %cell_nucleus = [];                  % if 1 - depicts cell nucleus with bigger condutivity, if 0 - no nucleus is depicetd
            u_entry.SNR = app.SNREditField.Value;                           % noise ratio
            u_entry.inv_solver_name = app.TypofrandominvsolverDropDown.Value;               % 'GN' (for Gauss Newton one step) or 'TV' (for Total Variation/PDIPM)'
            u_entry.num_trainingData = app.num_trainingdataEditField.Value;
            
            u_entry.load_fmdl= 1;
            u_entry.type_of_artefacts=app.typeofartifactsDropDown.Value;
            u_entry.size_file_samples_max= app.size_samplesnbEditField.Value;
        u_entry.size_file_single_data_max= app.size_single_datanbEditField.Value;

            save([app.nameEditField.Value '_user_entry.mat'], "u_entry")


        end

        % Button pushed function: GenerateTrainingSamplesButton
        function GenerateTrainingSamplesButtonPushed(app, event)
            script_GenerateData
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FowardModelTab = uitab(app.TabGroup);
            app.FowardModelTab.Title = 'Foward Model';
            app.Panel_fmdl = uipanel(app.FowardModelTab);
            app.Panel_fmdl.Title = 'Forward model';
            app.pB_fmdl_Make = uibutton(app.Panel_fmdl, 'push');
            app.pB_fmdl_Make.ButtonPushedFcn = createCallbackFcn(app, @pB_fmdl_MakePushed, true);
            app.pB_fmdl_Make.Text = 'Make Foward Model';
            app.cB_show_mesh_quality = uicheckbox(app.Panel_fmdl);
            app.cB_show_mesh_quality.Enable = 'off';
            app.cB_show_mesh_quality.Text = 'show mesh quality';
            app.pB_fmdl_Load = uibutton(app.Panel_fmdl, 'push');
            app.pB_fmdl_Load.ButtonPushedFcn = createCallbackFcn(app, @pB_fmdl_LoadPushed, true);
            app.pB_fmdl_Load.Text = 'Load';
            app.pB_fmdl_Save = uibutton(app.Panel_fmdl, 'push');
            app.pB_fmdl_Save.ButtonPushedFcn = createCallbackFcn(app, @pB_fmdl_SavePushed, true);
            app.pB_fmdl_Save.Text = 'Save';
            app.Panel_fmdl_solver_params = uipanel(app.Panel_fmdl);
            app.Panel_fmdl_solver_params.Title = 'Solving parameters';
            app.SolveLabel = uilabel(app.Panel_fmdl_solver_params);
            app.SolveLabel.Text = 'Solve';
            app.dD_fmdl_solve = uidropdown(app.Panel_fmdl_solver_params);
            app.dD_fmdl_solve.Items = {'eidors_default', 'fwd_solve_1st_order', 'aa_fwd_solve'};
            app.dD_fmdl_solve.Value = 'eidors_default';
            app.JacobianLabel = uilabel(app.Panel_fmdl_solver_params);
            app.JacobianLabel.Text = 'Jacobian';
            app.dD_fmdl_jac = uidropdown(app.Panel_fmdl_solver_params);
            app.dD_fmdl_jac.Items = {'eidors_default', 'jacobian_adjoint', 'aa_calc_jacobian', ''};
            app.dD_fmdl_jac.Value = 'eidors_default';
            app.System_matLabel = uilabel(app.Panel_fmdl_solver_params);
            app.System_matLabel.Text = 'System_mat';
            app.dD_fmdl_systmat = uidropdown(app.Panel_fmdl_solver_params);
            app.dD_fmdl_systmat.Items = {'eidors_default', 'system_mat_1st_order', 'aa_calc_system_mat', ''};
            app.dD_fmdl_systmat.Value = 'eidors_default';
            app.Miscperm_symLabel = uilabel(app.Panel_fmdl_solver_params);
            app.Miscperm_symLabel.Text = 'Misc.perm_sym';
            app.dD_fmdl_miscperm_sym = uidropdown(app.Panel_fmdl_solver_params);
            app.dD_fmdl_miscperm_sym.Items = {'''{n}'''};
            app.dD_fmdl_miscperm_sym.Value = '''{n}''';
            app.MeshLabel = uilabel(app.Panel_fmdl);
            app.MeshLabel.Text = 'Mesh';
            app.sw_show_mesh = uiswitch(app.Panel_fmdl, 'slider');
            app.sw_show_mesh.Items = {'Show', 'Hide'};
            app.sw_show_mesh.ItemsData = [1 0];
            app.sw_show_mesh.ValueChangedFcn = createCallbackFcn(app, @sw_show_meshValueChanged, true);
            app.sw_show_mesh.Value = 1;
            app.ElectrodesLabel = uilabel(app.Panel_fmdl);
            app.ElectrodesLabel.Text = 'Electrodes #';
            app.sw_show_elec_nb = uiswitch(app.Panel_fmdl, 'slider');
            app.sw_show_elec_nb.Items = {'Show', 'Hide'};
            app.sw_show_elec_nb.ItemsData = [1 0];
            app.sw_show_elec_nb.ValueChangedFcn = createCallbackFcn(app, @sw_show_elec_nbValueChanged, true);
            app.sw_show_elec_nb.Value = 1;
            app.MeasurementchamberdesignPanel = uipanel(app.FowardModelTab);
            app.MeasurementchamberdesignPanel.Title = 'Measurement chamber design';
            app.BodyPanel = uipanel(app.MeasurementchamberdesignPanel);
            app.BodyPanel.Title = 'Body';
            app.TypDropDownLabel = uilabel(app.BodyPanel);
            app.TypDropDownLabel.Text = 'Typ';
            app.dD_chamber_typ = uidropdown(app.BodyPanel);
            app.dD_chamber_typ.Items = {'Cylinder', 'Cubic', '2D_Circ', ''};
            app.dD_chamber_typ.Value = 'Cylinder';
            app.XEditFieldLabel = uilabel(app.BodyPanel);
            app.XEditFieldLabel.Text = 'X';
            app.chamber_boxX = uieditfield(app.BodyPanel, 'numeric');
            app.chamber_boxX.Value = 1;
            app.YEditFieldLabel = uilabel(app.BodyPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.chamber_boxY = uieditfield(app.BodyPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.BodyPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.chamber_boxZ = uieditfield(app.BodyPanel, 'numeric');
            app.chamber_boxZ.Value = 1;
            app.FEMRefinementEditFieldLabel = uilabel(app.BodyPanel);
            app.FEMRefinementEditFieldLabel.Text = 'FEM Refinement';
            app.chamber_fem = uieditfield(app.BodyPanel, 'numeric');
            app.chamber_fem.Value = 0.3;
            app.electrodes = uipanel(app.MeasurementchamberdesignPanel);
            app.electrodes.Title = 'Electrode layouts';
            app.t_elec_layout = uitable(app.electrodes);
            app.t_elec_layout.ColumnName = {'Design'; 'Diameter'; 'Number'; 'Position'; 'Form'; 'Diameter_Width'; 'Height'; ''};
            app.t_elec_layout.RowName = {};
            app.t_elec_layout.ColumnEditable = true;
            app.pB_elec_layout_Add = uibutton(app.electrodes, 'push');
            app.pB_elec_layout_Add.ButtonPushedFcn = createCallbackFcn(app, @pB_elec_layout_AddPushed, true);
            app.pB_elec_layout_Add.Text = '+';
            app.pB_elec_layout_Del = uibutton(app.electrodes, 'push');
            app.pB_elec_layout_Del.ButtonPushedFcn = createCallbackFcn(app, @pB_elec_layout_DelPushed, true);
            app.pB_elec_layout_Del.Text = '-';
            app.electrode_layout_info = uitextarea(app.electrodes);
            app.electrode_layout_info.Editable = 'off';
            app.electrode_layout_info.Value = {'For "array"-layout the number can be: '; '- an integer > square design '; '- a double to specify the number of rows and colums  e.g. Number = 5.02  >> nb rows (in x) = 5; nb columns (in y) = 2'};
            app.pB_chamber_Load = uibutton(app.MeasurementchamberdesignPanel, 'push');
            app.pB_chamber_Load.ButtonPushedFcn = createCallbackFcn(app, @pB_chamber_LoadPushed, true);
            app.pB_chamber_Load.Text = 'Load';
            app.pB_chamber_Save = uibutton(app.MeasurementchamberdesignPanel, 'push');
            app.pB_chamber_Save.ButtonPushedFcn = createCallbackFcn(app, @pB_chamber_SavePushed, true);
            app.pB_chamber_Save.Text = 'Save';
            app.NameLabel = uilabel(app.MeasurementchamberdesignPanel);
            app.NameLabel.Text = 'Name';
            app.chamber_name = uieditfield(app.MeasurementchamberdesignPanel, 'text');
            app.chamber_name.Value = 'ChamberDesign_defaultname';
            app.PatternTab = uitab(app.TabGroup);
            app.PatternTab.Title = 'Pattern';
            app.InjectionPatternPanel = uipanel(app.PatternTab);
            app.InjectionPatternPanel.Title = 'Injection Pattern';
            app.InjectionPatternTable = uitable(app.InjectionPatternPanel);
            app.InjectionPatternTable.ColumnName = {'nr'; 'Inj-'; 'Inj+'};
            app.InjectionPatternTable.RowName = {};
            app.pB_pattern_Display = uibutton(app.PatternTab, 'push');
            app.pB_pattern_Display.ButtonPushedFcn = createCallbackFcn(app, @pB_pattern_DisplayPushed, true);
            app.pB_pattern_Display.Text = 'Display Injection Pattern';
            app.MeasPatternPanel = uipanel(app.PatternTab);
            app.MeasPatternPanel.Title = 'Meas Pattern';
            app.MeasPatternTable = uitable(app.MeasPatternPanel);
            app.MeasPatternTable.ColumnName = {'Meas -'; 'Meas +'};
            app.MeasPatternTable.RowName = {};
            app.pB_pattern_Make = uibutton(app.PatternTab, 'push');
            app.pB_pattern_Make.ButtonPushedFcn = createCallbackFcn(app, @pB_pattern_MakePushed, true);
            app.pB_pattern_Make.Text = 'Pattern create';
            app.cB_pattern_hold = uicheckbox(app.PatternTab);
            app.cB_pattern_hold.Enable = 'off';
            app.cB_pattern_hold.Text = 'hold existing pattern';
            app.cB_pattern_animated = uicheckbox(app.PatternTab);
            app.cB_pattern_animated.Text = 'Animated';
            app.cB_pattern_with_meas = uicheckbox(app.PatternTab);
            app.cB_pattern_with_meas.Text = 'with meas. Pattern';
            app.cB_pattern_own_display = uicheckbox(app.PatternTab);
            app.cB_pattern_own_display.Text = 'Own_DisplayScript';
            app.cB_pattern_all_display = uicheckbox(app.PatternTab);
            app.cB_pattern_all_display.Text = 'all display';
            app.InjectionNrDropDownLabel = uilabel(app.PatternTab);
            app.InjectionNrDropDownLabel.Text = 'Injection Nr.';
            app.dD_actual_inj_nb = uidropdown(app.PatternTab);
            app.dD_actual_inj_nb.Items = {'1', ''};
            app.dD_actual_inj_nb.ValueChangedFcn = createCallbackFcn(app, @dD_actual_inj_nbValueChanged, true);
            app.dD_actual_inj_nb.Value = '1';
            app.Panel_pattern_param = uipanel(app.PatternTab);
            app.Panel_pattern_param.Title = 'Pattern parameter';
            app.pattern_special_info = uitextarea(app.Panel_pattern_param);
            app.pattern_special_info.Editable = 'off';
            app.pattern_special_info.Value = {'First pattern is [x+1,y+1]'};
            app.pattern_option_info = uitextarea(app.Panel_pattern_param);
            app.pattern_option_info.Editable = 'off';
            app.pattern_option_info.Value = {'if contradictory options are specified, only the last applies'; '%       ''channel_voltages'''; '%       -> get only the voltages at electrodes'; '%      ''no_meas_current'' / ''meas_current'''; '%         -> do / don''t make mesurements on current carrying electrodes'; '%         -> to not make measurements on the electrodes next to the current carrying,'; '%            use ''no_meas_current_next1'''; '%         -> to not make measurements on the two electrodes next to the current carrying,'; '%            use ''no_meas_current_next2'''; '%         -> to not make measurements on the three electrodes next to the current carrying,'; '%            use ''no_meas_current_next3'''; '%      ''rotate_meas'' / ''no_rotate_meas'''; '%         -> do / don''t rotate measurements with stimulation pattern'; '%      ''do_redundant'' / ''no_redundant'''; '%         -> do / don''t make reciprocally redundant measures'; '%      ''balance_inj'' / ''no_balance_inj'''; '%         -> do / don''t draw current from all electrodes so total'; '%            injection is zero (useful for mono patterns)'; '%      ''balance_meas'' / ''no_balance_meas'''; '%         -> do / don''t subtrant measurement from all electrodes so total'; '%            average measurement is zero (useful for mono patterns)'};
            app.AmplitudeALabel = uilabel(app.Panel_pattern_param);
            app.AmplitudeALabel.Text = 'Amplitude [A]';
            app.pattern_amplitude = uieditfield(app.Panel_pattern_param, 'numeric');
            app.pattern_amplitude.Value = 1;
            app.PatterningfunctionLabel = uilabel(app.Panel_pattern_param);
            app.PatterningfunctionLabel.Text = 'Patterning function';
            app.dD_pattern_func = uidropdown(app.Panel_pattern_param);
            app.dD_pattern_func.Items = {'mk_stim_pattern(EIDORS)', 'mk_stim_pattern_Array', 'mk_stim_3Dpattern'};
            app.dD_pattern_func.ValueChangedFcn = createCallbackFcn(app, @dD_pattern_funcValueChanged, true);
            app.dD_pattern_func.Value = 'mk_stim_pattern(EIDORS)';
            app.InjectionpatternLabel = uilabel(app.Panel_pattern_param);
            app.InjectionpatternLabel.Text = 'Injection pattern';
            app.dD_pattern_inj_type = uidropdown(app.Panel_pattern_param);
            app.dD_pattern_inj_type.Items = {'adjacent (EIDORS)', 'opposite (EIDORS)', 'user defined (EIDORS)', 'array_ad_simple', 'array_ad_full', 'array_ad_line', 'array_op'};
            app.dD_pattern_inj_type.ItemsData = {'{ad}', '{op}', 'user defined', 'array_ad_simple', 'array_ad_full', 'array_ad_line', 'array_op', ''};
            app.dD_pattern_inj_type.Value = '{ad}';
            app.InjuserpatternLabel = uilabel(app.Panel_pattern_param);
            app.InjuserpatternLabel.Text = 'Inj. user pattern';
            app.pattern_inj_special = uieditfield(app.Panel_pattern_param, 'text');
            app.pattern_inj_special.Value = '[1 2]';
            app.MeasurementpatternLabel = uilabel(app.Panel_pattern_param);
            app.MeasurementpatternLabel.Text = 'Measurement pattern';
            app.dD_pattern_meas_typ = uidropdown(app.Panel_pattern_param);
            app.dD_pattern_meas_typ.Items = {'adjacent (EIDORS)', 'opposite (EIDORS)', 'user defined (EIDORS)', 'array_ad_simple', 'array_ad_full', 'array_ad_line', 'array_op', ''};
            app.dD_pattern_meas_typ.ItemsData = {'{ad}', '{op}', 'user defined', 'array_ad_simple', 'array_ad_full', 'array_ad_line', 'array_op', ''};
            app.dD_pattern_meas_typ.Value = '{ad}';
            app.MeasuserpatternLabel = uilabel(app.Panel_pattern_param);
            app.MeasuserpatternLabel.Text = 'Meas. user pattern';
            app.pattern_meas_special = uieditfield(app.Panel_pattern_param, 'text');
            app.pattern_meas_special.Value = '[1 2]';
            app.OptionsLabel = uilabel(app.Panel_pattern_param);
            app.OptionsLabel.Text = 'Options';
            app.pattern_option = uitextarea(app.Panel_pattern_param);
            app.pattern_option.Value = {'meas_current'};
            app.SolvefowardTab = uitab(app.TabGroup);
            app.SolvefowardTab.Title = 'Solve foward';
            app.ax_sim_data = uiaxes(app.SolvefowardTab);
            title(app.ax_sim_data, 'Title')
            xlabel(app.ax_sim_data, 'X')
            ylabel(app.ax_sim_data, 'Y')
            zlabel(app.ax_sim_data, 'Z')
            app.SolveFowardButton = uibutton(app.SolvefowardTab, 'push');
            app.SolveFowardButton.ButtonPushedFcn = createCallbackFcn(app, @SolveFowardButtonPushed, true);
            app.SolveFowardButton.Text = 'Solve Foward';
            app.add_obj_inFEM = uicheckbox(app.SolvefowardTab);
            app.add_obj_inFEM.Enable = 'off';
            app.add_obj_inFEM.Text = 'Add object in FEM';
            app.ObjectsPanel = uipanel(app.SolvefowardTab);
            app.ObjectsPanel.Title = 'Objects';
            app.t_objects = uitable(app.ObjectsPanel);
            app.t_objects.ColumnName = {'PosX'; 'PosY'; 'PosZ'; 'Radius'};
            app.t_objects.RowName = {};
            app.t_objects.ColumnEditable = true;
            app.RowPlusButton_CellPosition = uibutton(app.ObjectsPanel, 'push');
            app.RowPlusButton_CellPosition.ButtonPushedFcn = createCallbackFcn(app, @RowPlusButton_CellPositions_Pushed, true);
            app.RowPlusButton_CellPosition.Text = '+';
            app.RowMinusButton_CellPosition = uibutton(app.ObjectsPanel, 'push');
            app.RowMinusButton_CellPosition.ButtonPushedFcn = createCallbackFcn(app, @RowMinusButton_CellPositions_Pushed, true);
            app.RowMinusButton_CellPosition.Text = '-';
            app.save_fwd_sol = uicheckbox(app.SolvefowardTab);
            app.save_fwd_sol.Text = 'Save foward solution';
            app.save_fwd_sol.Value = true;
            app.MediumconductivityEditFieldLabel = uilabel(app.SolvefowardTab);
            app.MediumconductivityEditFieldLabel.Text = 'Medium conductivity';
            app.medium_conduct = uieditfield(app.SolvefowardTab, 'numeric');
            app.medium_conduct.Value = 1;
            app.SolveInverseTab = uitab(app.TabGroup);
            app.SolveInverseTab.Title = 'Solve Inverse';
            app.Makeinv_mdlSimulateButton = uibutton(app.SolveInverseTab, 'push');
            app.Makeinv_mdlSimulateButton.ButtonPushedFcn = createCallbackFcn(app, @Makeinv_mdlSimulateButtonPushed, true);
            app.Makeinv_mdlSimulateButton.Text = 'Make inv_mdl/Simulate';
            app.SliceZHeightSliderLabel = uilabel(app.SolveInverseTab);
            app.SliceZHeightSliderLabel.Text = 'Slice Z-Height';
            app.SliceZHeightSlider = uislider(app.SolveInverseTab);
            app.SliceZHeightSlider.ValueChangedFcn = createCallbackFcn(app, @SliceZHeightSliderValueChanged, true);
            app.SliceZHeightSlider.Value = 50;
            app.Transparency3DimdlSliderLabel = uilabel(app.SolveInverseTab);
            app.Transparency3DimdlSliderLabel.Text = 'Transparency 3D imdl';
            app.Transparency3DimdlSlider = uislider(app.SolveInverseTab);
            app.Transparency3DimdlSlider.ValueChangedFcn = createCallbackFcn(app, @Transparency3DimdlSliderValueChanged, true);
            app.Transparency3DimdlSlider.Value = 100;
            app.SavesimulationresultsButton = uibutton(app.SolveInverseTab, 'push');
            app.SavesimulationresultsButton.ButtonPushedFcn = createCallbackFcn(app, @SavesimulationresultsButtonPushed, true);
            app.SavesimulationresultsButton.Text = 'Save simulation results';
            app.LoadsimulationresultsButton = uibutton(app.SolveInverseTab, 'push');
            app.LoadsimulationresultsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsimulationresultsButtonPushed, true);
            app.LoadsimulationresultsButton.Text = 'Load simulation results';
            app.SimulationdatasetnameEditFieldLabel = uilabel(app.SolveInverseTab);
            app.SimulationdatasetnameEditFieldLabel.Text = 'Simulation data set name';
            app.SimulationdatasetnameEditField = uieditfield(app.SolveInverseTab, 'text');
            app.SimulationdatasetnameEditField.Value = 'Simulation default';
            app.inv_modelsolvingparameterPanel = uipanel(app.SolveInverseTab);
            app.inv_modelsolvingparameterPanel.Title = 'inv_model solving parameter';
            app.imdlsolveDropDownLabel = uilabel(app.inv_modelsolvingparameterPanel);
            app.imdlsolveDropDownLabel.Text = 'imdl.solve';
            app.imdlsolveDropDown = uidropdown(app.inv_modelsolvingparameterPanel);
            app.imdlsolveDropDown.Items = {'eidors_default', 'inv_solve_TSVD', 'inv_solve_TV_irls', 'inv_solve_TV_pdipm', 'inv_solve_abs_GN_constrain', 'inv_solve_abs_GN_prior', 'inv_solve_abs_annealingMetropolis_params', 'inv_solve_abs_annealingSimplex_params', 'inv_solve_abs_pdipm', 'inv_solve_backproj', 'inv_solve_cg', 'inv_solve_conj_grad', 'inv_solve_core', 'inv_solve_diff_GN_one_step', 'inv_solve_diff_kalman', 'inv_solve_diff_pdipm', 'inv_solve_gn', 'inv_solve_mcmc', 'inv_solve_time_prior', 'inv_solve_trunc_iterative '};
            app.imdlsolveDropDown.Value = 'eidors_default';
            app.imdlRtR_priorDropDownLabel = uilabel(app.inv_modelsolvingparameterPanel);
            app.imdlRtR_priorDropDownLabel.Text = 'imdl.RtR_prior';
            app.imdlRtR_priorDropDown = uidropdown(app.inv_modelsolvingparameterPanel);
            app.imdlRtR_priorDropDown.Items = {'eidors_default', 'prior_TV', 'prior_covar', 'prior_exponential_covar', 'prior_gaussian_HPF', 'prior_gaussian_likelihood', 'prior_laplace', 'prior_laplace_old', 'prior_movement', 'prior_noser', 'prior_tikhonov', 'prior_time_smooth', ''};
            app.imdlRtR_priorDropDown.Value = 'eidors_default';
            app.imdlhyperparameterfuncDropDownLabel = uilabel(app.inv_modelsolvingparameterPanel);
            app.imdlhyperparameterfuncDropDownLabel.Text = 'imdl.hyperparameter.func';
            app.imdlhyperparameterfuncDropDown = uidropdown(app.inv_modelsolvingparameterPanel);
            app.imdlhyperparameterfuncDropDown.Items = {'NOT USED', 'aa_calc_noise_figure', ''};
            app.imdlhyperparameterfuncDropDown.Value = 'NOT USED';
            app.imdlreconst_typeDropDownLabel = uilabel(app.inv_modelsolvingparameterPanel);
            app.imdlreconst_typeDropDownLabel.Text = 'imdl.reconst_type';
            app.imdlreconst_typeDropDown = uidropdown(app.inv_modelsolvingparameterPanel);
            app.imdlreconst_typeDropDown.Items = {'difference', 'static'};
            app.imdlreconst_typeDropDown.Value = 'difference';
            app.imdlhyperparametervalueEditFieldLabel = uilabel(app.inv_modelsolvingparameterPanel);
            app.imdlhyperparametervalueEditFieldLabel.Text = 'imdl.hyperparameter.value';
            app.imdlhyperparametervalueEditField = uieditfield(app.inv_modelsolvingparameterPanel, 'numeric');
            app.imdlhyperparametervalueEditField.Value = 0.01;
            app.imdljacobian_bkgndvalueEditFieldLabel = uilabel(app.inv_modelsolvingparameterPanel);
            app.imdljacobian_bkgndvalueEditFieldLabel.Text = 'imdl.jacobian_bkgnd.value';
            app.imdljacobian_bkgndvalueEditField = uieditfield(app.inv_modelsolvingparameterPanel, 'numeric');
            app.imdljacobian_bkgndvalueEditField.Value = 1;
            app.imdlhyperparameterparametersLabel = uilabel(app.inv_modelsolvingparameterPanel);
            app.imdlhyperparameterparametersLabel.Text = 'imdl.hyperparameter.parameters';
            app.imdlhyperparameterparametersDropDown = uidropdown(app.inv_modelsolvingparameterPanel);
            app.imdlhyperparameterparametersDropDown.Items = {'NOT USED', 'noise_figure', ''};
            app.imdlhyperparameterparametersDropDown.Value = 'NOT USED';
            app.imdljacobian_bkgndfuncDropDownLabel = uilabel(app.inv_modelsolvingparameterPanel);
            app.imdljacobian_bkgndfuncDropDownLabel.Text = 'imdl.jacobian_bkgnd.func';
            app.imdljacobian_bkgndfuncDropDown = uidropdown(app.inv_modelsolvingparameterPanel);
            app.imdljacobian_bkgndfuncDropDown.Items = {'NOT USED'};
            app.imdljacobian_bkgndfuncDropDown.Value = 'NOT USED';
            app.SlicesforevalPanel = uipanel(app.SolveInverseTab);
            app.SlicesforevalPanel.Title = 'Slices for eval';
            app.SimEvalSliceUITable = uitable(app.SlicesforevalPanel);
            app.SimEvalSliceUITable.ColumnName = {'x='; 'y='; 'z='};
            app.SimEvalSliceUITable.RowName = {};
            app.SimEvalSliceUITable.ColumnEditable = true;
            app.RowPlusButton_SimSlice = uibutton(app.SlicesforevalPanel, 'push');
            app.RowPlusButton_SimSlice.ButtonPushedFcn = createCallbackFcn(app, @RowPlusButton_SimSlicePushed, true);
            app.RowPlusButton_SimSlice.Text = '+';
            app.RowMinusButton_SimSlice = uibutton(app.SlicesforevalPanel, 'push');
            app.RowMinusButton_SimSlice.ButtonPushedFcn = createCallbackFcn(app, @RowMinusButton_SimSlicePushed, true);
            app.RowMinusButton_SimSlice.Text = '-';
            app.SimGREITTable = uitable(app.SolveInverseTab);
            app.SimGREITTable.ColumnName = {'AmpR'; 'PosError'; 'Res'; 'ShapeDef'; 'Ringing'};
            app.SimGREITTable.RowName = {};
            app.SimGREITTable.ColumnEditable = true;
            app.Makeinv_mdlButton = uibutton(app.SolveInverseTab, 'push');
            app.Makeinv_mdlButton.ButtonPushedFcn = createCallbackFcn(app, @Makeinv_mdlButtonPushed, true);
            app.Makeinv_mdlButton.Text = 'Make inv_mdl';
            app.loadandrecontaructmeasButton = uibutton(app.SolveInverseTab, 'push');
            app.loadandrecontaructmeasButton.ButtonPushedFcn = createCallbackFcn(app, @loadandrecontaructmeasButtonPushed, true);
            app.loadandrecontaructmeasButton.Text = 'load and recontaructmeas';
            app.Generate_user_entryTab = uitab(app.TabGroup);
            app.Generate_user_entryTab.Tooltip = {''};
            app.Generate_user_entryTab.Title = 'Generate_user_entry';
            app.num_trainingdataEditFieldLabel = uilabel(app.Generate_user_entryTab);
            app.num_trainingdataEditFieldLabel.Text = 'num_trainingdata';
            app.num_trainingdataEditField = uieditfield(app.Generate_user_entryTab, 'numeric');
            app.num_trainingdataEditField.Tooltip = {'Enter numbers of samples to generate'};
            app.num_trainingdataEditField.Value = 1000;
            app.nameEditFieldLabel = uilabel(app.Generate_user_entryTab);
            app.nameEditFieldLabel.Text = 'name';
            app.nameEditField = uieditfield(app.Generate_user_entryTab, 'text');
            app.nameEditField.Value = 'cytoplasm';
            app.rangenumofcellEditFieldLabel = uilabel(app.Generate_user_entryTab);
            app.rangenumofcellEditFieldLabel.Text = 'range num of cell ';
            app.rangenumofcellEditField = uieditfield(app.Generate_user_entryTab, 'text');
            app.rangenumofcellEditField.Tooltip = {'Enter a range of  number of cell; like [2,3] >  random generation between 2 and 3 cells'};
            app.rangenumofcellEditField.Value = '[2 5]';
            app.rangebufferconductivityEditFieldLabel = uilabel(app.Generate_user_entryTab);
            app.rangebufferconductivityEditFieldLabel.Text = 'range buffer conductivity';
            app.rangebufferconductivityEditField = uieditfield(app.Generate_user_entryTab, 'text');
            app.rangebufferconductivityEditField.Tooltip = {'Enter a range of  number of cell; like [1.5,2] >  random generation between 2 and 3 '};
            app.rangebufferconductivityEditField.Value = '[0.5 0.5]';
            app.rangecellradiusEditFieldLabel = uilabel(app.Generate_user_entryTab);
            app.rangecellradiusEditFieldLabel.Text = 'range cell radius';
            app.rangecellradiusEditField = uieditfield(app.Generate_user_entryTab, 'text');
            app.rangecellradiusEditField.Tooltip = {'Enter a range of  radius of cell; like [0.5 0.5] '};
            app.rangecellradiusEditField.Value = '[0.4 0.5]';
            app.rangecellconductivityEditFieldLabel = uilabel(app.Generate_user_entryTab);
            app.rangecellconductivityEditFieldLabel.Text = 'range cell conductivity ';
            app.rangecellconductivityEditField = uieditfield(app.Generate_user_entryTab, 'text');
            app.rangecellconductivityEditField.Tooltip = {'Enter a range of  number of cell; like [2,3] >  random generation between 2 and 3 cells'};
            app.rangecellconductivityEditField.Value = '[1 1.5 1; 2 2.5 0.6]';
            app.SNREditFieldLabel = uilabel(app.Generate_user_entryTab);
            app.SNREditFieldLabel.Text = 'SNR';
            app.SNREditField = uieditfield(app.Generate_user_entryTab, 'numeric');
            app.SNREditField.Value = 20;
            app.Savename_user_entrymatButton = uibutton(app.Generate_user_entryTab, 'push');
            app.Savename_user_entrymatButton.ButtonPushedFcn = createCallbackFcn(app, @Savename_user_entrymatButtonPushed, true);
            app.Savename_user_entrymatButton.Text = 'Save name_user_entry.mat ';
            app.typeofartifactsDropDownLabel = uilabel(app.Generate_user_entryTab);
            app.typeofartifactsDropDownLabel.Text = 'type of artifacts';
            app.typeofartifactsDropDown = uidropdown(app.Generate_user_entryTab);
            app.typeofartifactsDropDown.Items = {'Cells', 'Antigens', 'Random'};
            app.typeofartifactsDropDown.Value = 'Cells';
            app.TypofrandominvsolverDropDownLabel = uilabel(app.Generate_user_entryTab);
            app.TypofrandominvsolverDropDownLabel.Enable = 'off';
            app.TypofrandominvsolverDropDownLabel.Text = 'Typ of random invsolver';
            app.TypofrandominvsolverDropDown = uidropdown(app.Generate_user_entryTab);
            app.TypofrandominvsolverDropDown.Items = {'GN', 'TV'};
            app.TypofrandominvsolverDropDown.Enable = 'off';
            app.TypofrandominvsolverDropDown.Value = 'GN';
            app.GenerateTrainingSamplesButton = uibutton(app.Generate_user_entryTab, 'push');
            app.GenerateTrainingSamplesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTrainingSamplesButtonPushed, true);
            app.GenerateTrainingSamplesButton.Text = 'Generate Training Samples';
            app.size_single_datanbEditFieldLabel = uilabel(app.Generate_user_entryTab);
            app.size_single_datanbEditFieldLabel.Text = 'size_single_data(nb)';
            app.size_single_datanbEditField = uieditfield(app.Generate_user_entryTab, 'numeric');
            app.size_single_datanbEditField.Value = 250;
            app.size_samplesnbEditFieldLabel = uilabel(app.Generate_user_entryTab);
            app.size_samplesnbEditFieldLabel.Text = 'size_samples (nb)';
            app.size_samplesnbEditField = uieditfield(app.Generate_user_entryTab, 'numeric');
            app.size_samplesnbEditField.Value = 1000;
            app.version_info = uieditfield(app.UIFigure, 'text');
            app.version_info.Editable = 'off';
            app.version_info.Value = 'FwdModel_App by Metz';
            app.fwd_modelnameEditFieldLabel = uilabel(app.UIFigure);
            app.fwd_modelnameEditFieldLabel.Text = 'fwd_model.name';
            app.fmdl_name = uieditfield(app.UIFigure, 'text');
            app.fmdl_name.Value = 'fwd_model_defaultname';
            app.pB_eit_env_Save = uibutton(app.UIFigure, 'push');
            app.pB_eit_env_Save.ButtonPushedFcn = createCallbackFcn(app, @pB_eit_env_SavePushed, true);
            app.pB_eit_env_Save.Text = 'Save EIT environement';
            app.PathEIDORSEditFieldLabel = uilabel(app.UIFigure);
            app.PathEIDORSEditFieldLabel.Text = 'Path EIDORS';
            app.eidors_path = uieditfield(app.UIFigure, 'text');
            app.pB_eit_env_Load = uibutton(app.UIFigure, 'push');
            app.pB_eit_env_Load.ButtonPushedFcn = createCallbackFcn(app, @pB_eit_env_LoadPushed, true);
            app.pB_eit_env_Load.Text = 'Load EIT environement';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FwdModel_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end